classdef scopeInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % nScope M...
        scopePlot             matlab.ui.control.UIAxes           % Scope
        channelPanel          matlab.ui.container.Panel          % Channels
        channelOneCheckBox    matlab.ui.control.CheckBox         % Channel 1
        channelTwoCheckBox    matlab.ui.control.CheckBox         % Channel 2
        channelThreeCheckBox  matlab.ui.control.CheckBox         % Channel 3
        channelFourCheckBox   matlab.ui.control.CheckBox         % Channel 4
        statusPanel           matlab.ui.container.Panel          % Status
        LabelLamp             matlab.ui.control.Label            % Connection
        connectionLamp        matlab.ui.control.Lamp            
        LabelLamp2            matlab.ui.control.Label            % Power Su...
        Lamp                  matlab.ui.control.Lamp            
        LabelLamp3            matlab.ui.control.Label            % Data
        Lamp2                 matlab.ui.control.Lamp            
        controlPanel          matlab.ui.container.Panel          % Controls
        timeSlider            matlab.ui.control.Slider           % [1.00000...
        LabelSlider           matlab.ui.control.Label            % Time Sweep
        timeField             matlab.ui.control.NumericEditField % [-Inf Inf]
        voltageSlider         matlab.ui.control.Slider           % [1.00000...
        Label                 matlab.ui.control.Label            % Voltage ...
        voltageField          matlab.ui.control.NumericEditField % [-Inf Inf]
        voltageBiasSlider     matlab.ui.control.Slider           % [-5 5]
        Label5                matlab.ui.control.Label            % Voltage ...
        voltageBiasField      matlab.ui.control.NumericEditField % [-Inf Inf]
        StateButton           matlab.ui.control.StateButton      % Pause
        LabelNumericEditField matlab.ui.control.Label            % Ch1 Peak...
        peakVoltageOne        matlab.ui.control.NumericEditField % [-Inf Inf]
        Label2                matlab.ui.control.Label            % Ch2 Peak...
        peakVoltageTwo        matlab.ui.control.NumericEditField % [-Inf Inf]
        Label3                matlab.ui.control.Label            % Ch3 Peak...
        peakVoltageThree      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label4                matlab.ui.control.Label            % Ch4 Peak...
        prakVoltageFour       matlab.ui.control.NumericEditField % [-Inf Inf]
    end

    %% nScope Matlab Interface
    %  Adam Zeloof
    %  10/5/2016
    %  Version alpha-0.1
    
    properties (Access = private)
        sampleRate
        timeSpan
        voltageSpan
        gain
        numSamples
        liveData
        timeSamples
        running
        isConnected
        plotCounter
        voltageBias
        ch1
        ch2
        ch3
        ch4
    end

    methods (Access = private)
        
        function status = connectScope(app, trys)
            delay = 5; %seconds
            if (trys > 0)
                try
                    nScopeAPI('open',1);
                    status = 1;
                catch
                    disp('Scope not found, trying again in 5 seconds...');
                    pause(delay);
                    app.connectScope(trys-1);
                    status = 0;
                end
            else
               disp('No scope was found, giving up.')
               status = 0;
            end
        end

        function setupScope(app)
            app.ch1 = app.channelOneCheckBox.Value;
            app.ch2 = app.channelTwoCheckBox.Value;
            app.ch3 = app.channelThreeCheckBox.Value;
            app.ch4 = app.channelFourCheckBox.Value;
            if app.isConnected
                nScopeAPI('setChannelsOn',app.ch1,app.ch2,app.ch3,app.ch4);         % turn on Channel 1, and no others
                nScopeAPI('setCh1gain',app.gain);                  % set the gain on Channel 1
                nScopeAPI('setSampleRateInHz',app.sampleRate);          % set the sample rate
                nScopeAPI('requestData', app.numSamples);       % request the samples from nScope
            end
            app.scopePlot.YLim = [-app.voltageSpan+app.voltageBias,app.voltageSpan+app.voltageBias];
            app.scopePlot.XLim = [0, app.timeSpan];
        end

        function run(app)
            % The try/catch/breaks in this function are stupid, but protect from errors upon quitting the app
            if app.running
                app.plotCounter = 0;
                while(app.isConnected && nScopeAPI('requestHasData'))
                    app.plotCounter = app.plotCounter + 1;
                    if app.ch1
                        app.liveData(app.plotCounter, 1) = nScopeAPI('readData', 1);
                        app.peakVoltageOne.Value = max(app.liveData(:,1));
                    end
                    if app.ch2
                        app.liveData(app.plotCounter, 2) = nScopeAPI('readData', 2);
                        app.peakVoltageTwo.Value = max(app.liveData(:,2));
                    end
                    if app.ch3
                        app.liveData(app.plotCounter, 3) = nScopeAPI('readData', 3);
                        app.peakVoltageThree.Value = max(app.liveData(:,3));
                    end
                    if app.ch4
                        app.liveData(app.plotCounter, 4) = nScopeAPI('readData', 4);
                        app.peakVoltageFour.Value = max(app.liveData(:,4));
                    end
                    plot(app.scopePlot, app.timeSamples, app.liveData);
                    pause(.001);
                    try
                        if app.isConnected == 0
                            break
                        end
                    catch
                        break
                    end
                end
                try
                    if app.isConnected
                        app.plotCounter = 0;
                        nScopeAPI('requestData', app.numSamples);
                        app.run();
                    end
                catch
                end
            end
        end

        function updateSettings(app)
            app.sampleRate = 50; %hz
            app.timeSpan = app.timeSlider.Value; %seconds
            app.voltageSpan = app.voltageSlider.Value;
            app.voltageBias = app.voltageBiasSlider.Value;
            app.gain = 1;
            app.numSamples = round(app.sampleRate * app.timeSpan);
            app.liveData = NaN(app.numSamples,4);
            app.timeSamples = linspace(0, app.timeSpan, app.numSamples);
            app.plotCounter = 0;
            app.setupScope();
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.running = 1;
            app.isConnected = 0;
            if app.connectScope(5)
                app.isConnected = 1;
                app.connectionLamp.Enable = 'on';
                app.updateSettings();
                app.run();
            end
        end

        % channelOneCheckBox value changed function
        function ch1Val(app)
            value = app.channelOneCheckBox.Value;
            app.updateSettings();
        end

        % channelTwoCheckBox value changed function
        function ch2Val(app)
            value = app.channelTwoCheckBox.Value;
            app.updateSettings();
        end

        % channelThreeCheckBox value changed function
        function ch3Val(app)
            value = app.channelThreeCheckBox.Value;
            app.updateSettings();
        end

        % channelFourCheckBox value changed function
        function ch4Val(app)
            value = app.channelFourCheckBox.Value;
            app.updateSettings();
        end

        % timeSlider value changed function
        function timeSliderChange(app)
            value = app.timeSlider.Value;
            app.timeField.Value = value;
            app.timeSpan = value;
            app.updateSettings();
        end

        % timeField value changed function
        function timeFieldChange(app)
            value = app.timeField.Value;
            app.timeSlider.Value = value;
            app.timeSpan = value;
            app.updateSettings();
        end

        % voltageSlider value changed function
        function voltageSliderChange(app)
            value = app.voltageSlider.Value;
            app.voltageField.Value = value;
            app.voltageSpan = value;
            app.voltageSpan = app.voltageSlider.Value;
            app.scopePlot.YLim = [-app.voltageSpan+app.voltageBias,app.voltageSpan+app.voltageBias];
            %app.updateSettings();
        end

        % voltageField value changed function
        function voltageFieldChange(app)
            value = app.voltageField.Value;
            app.voltageSlider.Value = value;
            app.voltageSpan = value;
            app.voltageSpan = app.voltageSlider.Value;
            app.scopePlot.YLim = [-app.voltageSpan+app.voltageBias,app.voltageSpan+app.voltageBias];
            %app.updateSettings();
        end

        % UIFigure close request function
        function shutdown(app)
            app.running = 0;
            app.isConnected = 0;
            try
                nScopeAPI('close', 1);
            catch
                disp('Tried to close scope, failed.');
            end
            pause(.1);
            app.delete();
        end

        % voltageBiasSlider value changed function
        function voltageBiasSliderChange(app)
            value = app.voltageBiasSlider.Value;
            app.voltageBiasField.Value = value;
            app.voltageBias = value;
            app.scopePlot.YLim = [-app.voltageSpan+app.voltageBias,app.voltageSpan+app.voltageBias];
        end

        % voltageBiasField value changed function
        function voltageBiasFieldChange(app)
            value = app.voltageBiasField.Value;
            app.voltageBiasSlider.Value = value;
            app.voltageBias = value;
            app.scopePlot.YLim = [-app.voltageSpan+app.voltageBias,app.voltageSpan+app.voltageBias];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'nScope Matlab Interface';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @shutdown);
            setAutoResize(app, app.UIFigure, true)
            app.scopePlot = uiaxes(app.UIFigure);
            title(app.scopePlot, 'Scope');
            xlabel(app.scopePlot, 'Time (sec)');
            ylabel(app.scopePlot, 'Voltage');
            app.channelPanel = uipanel(app.UIFigure);
            app.channelPanel.Title = 'Channels';
            app.channelPanel.Units = 'pixels';
            app.channelOneCheckBox = uicheckbox(app.channelPanel);
            app.channelOneCheckBox.ValueChangedFcn = createCallbackFcn(app, @ch1Val);
            app.channelOneCheckBox.Text = 'Channel 1';
            app.channelOneCheckBox.Value = true;
            app.channelTwoCheckBox = uicheckbox(app.channelPanel);
            app.channelTwoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ch2Val);
            app.channelTwoCheckBox.Text = 'Channel 2';
            app.channelThreeCheckBox = uicheckbox(app.channelPanel);
            app.channelThreeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ch3Val);
            app.channelThreeCheckBox.Text = 'Channel 3';
            app.channelFourCheckBox = uicheckbox(app.channelPanel);
            app.channelFourCheckBox.ValueChangedFcn = createCallbackFcn(app, @ch4Val);
            app.channelFourCheckBox.Text = 'Channel 4';
            app.statusPanel = uipanel(app.UIFigure);
            app.statusPanel.Title = 'Status';
            app.statusPanel.Units = 'pixels';
            app.LabelLamp = uilabel(app.statusPanel);
            app.LabelLamp.Text = 'Connection';
            app.connectionLamp = uilamp(app.statusPanel);
            app.connectionLamp.Enable = 'off';
            app.LabelLamp2 = uilabel(app.statusPanel);
            app.LabelLamp2.Text = 'Power Supply';
            app.Lamp = uilamp(app.statusPanel);
            app.Lamp.Enable = 'off';
            app.LabelLamp3 = uilabel(app.statusPanel);
            app.LabelLamp3.Text = 'Data';
            app.Lamp2 = uilamp(app.statusPanel);
            app.Lamp2.Enable = 'off';
            app.controlPanel = uipanel(app.UIFigure);
            app.controlPanel.Title = 'Controls';
            app.controlPanel.Units = 'pixels';
            app.timeSlider = uislider(app.controlPanel);
            app.timeSlider.Limits = [0.01 10];
            app.timeSlider.MajorTickLabels = {'0', '', '', '', '', '10'};
            app.timeSlider.ValueChangedFcn = createCallbackFcn(app, @timeSliderChange);
            app.timeSlider.Value = 1;
            app.LabelSlider = uilabel(app.controlPanel);
            app.LabelSlider.Text = 'Time Sweep';
            app.timeField = uieditfield(app.controlPanel, 'numeric');
            app.timeField.ValueChangedFcn = createCallbackFcn(app, @timeFieldChange);
            app.timeField.Value = 1;
            app.voltageSlider = uislider(app.controlPanel);
            app.voltageSlider.Limits = [0.01 6];
            app.voltageSlider.MajorTickLabels = {'0', '', '', '', '', '', '6'};
            app.voltageSlider.ValueChangedFcn = createCallbackFcn(app, @voltageSliderChange);
            app.voltageSlider.Value = 5;
            app.Label = uilabel(app.controlPanel);
            app.Label.Text = 'Voltage Scale';
            app.voltageField = uieditfield(app.controlPanel, 'numeric');
            app.voltageField.ValueChangedFcn = createCallbackFcn(app, @voltageFieldChange);
            app.voltageField.Value = 5;
            app.voltageBiasSlider = uislider(app.controlPanel);
            app.voltageBiasSlider.Limits = [-5 5];
            app.voltageBiasSlider.MajorTicks = [-5 0 5];
            app.voltageBiasSlider.ValueChangedFcn = createCallbackFcn(app, @voltageBiasSliderChange);
            app.Label5 = uilabel(app.controlPanel);
            app.Label5.Text = 'Voltage Bias';
            app.voltageBiasField = uieditfield(app.controlPanel, 'numeric');
            app.voltageBiasField.ValueChangedFcn = createCallbackFcn(app, @voltageBiasFieldChange);
            app.StateButton = uibutton(app.UIFigure, 'state');
            app.StateButton.Text = 'Pause';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Ch1 Peak Voltage';
            app.peakVoltageOne = uieditfield(app.UIFigure, 'numeric');
            app.peakVoltageOne.Editable = 'off';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Ch2 Peak Voltage';
            app.peakVoltageTwo = uieditfield(app.UIFigure, 'numeric');
            app.peakVoltageTwo.Editable = 'off';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Ch3 Peak Voltage';
            app.peakVoltageThree = uieditfield(app.UIFigure, 'numeric');
            app.peakVoltageThree.Editable = 'off';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Ch4 Peak Voltage';
            app.prakVoltageFour = uieditfield(app.UIFigure, 'numeric');
            app.prakVoltageFour.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = scopeInterface()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end